classdef EqualizerUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        FsradLabel_3       matlab.ui.control.Label
        LowSlider          matlab.ui.control.Slider
        Fs18FsradLabel     matlab.ui.control.Label
        BandSlider         matlab.ui.control.Slider
        FsradLabel_2       matlab.ui.control.Label
        HighSlider         matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
        RightPanel         matlab.ui.container.Panel
        LoadFileButton     matlab.ui.control.Button
        PlotButton         matlab.ui.control.Button
        ClearButton        matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        ReadyLabel         matlab.ui.control.Label
        PauseResumeButton  matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        y       % Data
        yr      % Data after pass filter
        fs      % Sampling Rate
        player  % Audio Player
        mflag   % Playing(1) or Pause(0)
        file    % Filename
        ls      % Low Slider value
        bs      % Band Slider value
        hs      % High Slider value
        lf      % Low Pass Filter
        bf      % Band Pass Filter
        hf      % High Pass Filter
        output1 % Data Passed LPF
        output2 % Data Passed BPF
        output3 % Data Passed HPF
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function Loadfunc(app, event)
            
            % Load filters
            disp("Load Filters");
            load filters.mat lpFilt;
            load filters.mat bpFilt;
            load filters.mat hpFilt;
            app.lf = lpFilt;
            app.bf = bpFilt;
            app.hf = hpFilt;
            
            % Load mp3 data, fs to y, fs
            disp("Load Files");
            app.file = uigetfile('*.mp3');
            [app.y,app.fs] = audioread(app.file);
            
            % Initializing
            app.ReadyLabel.Text = [ app.file "Loadded!"];
            app.player = audioplayer(app.y, app.fs);
            app.mflag = false;
            app.ls = 1;
            app.bs = 1;
            app.hs = 1;
            
            % Pre-Calculate for speed
            disp("Filter Calculate");
            tic
            app.output1 = filter(app.lf, app.y);
            app.output2 = filter(app.bf, app.y);
            app.output3 = filter(app.hf, app.y);
            toc
            
            disp("Load music : " + app.file);
            
        end

        % Button pushed function: PlotButton
        function Plotfunc(app, event)
            
            % Initializing values for Plotting
            NN0 = length(app.y);            % Length of music
            LL0 = ceil(log2(NN0));          % for FFT length
            LL1 = LL0-1;                    % Half of length
            LL2 = 2^LL0;                    % fft total length
            nn1 = 0:1:(2^LL1)-1;            % k index
            kk = nn1*(2*pi/LL2);            % k에 해당하는 w 주파수
            
            disp("Plot : Calculating");
            % Calculate Each Filter and multiple each slider value
            output1c = app.output1 .* app.ls;
            output2c = app.output2 .* app.bs;
            output3c = app.output3 .* app.hs;
            
            disp("Plot : Merging...");
            % Sum each filter passed data and save
            o1 = output1c + output2c + output3c;
            app.yr = o1;            
            o2 = fft(o1, LL2);
            o3 = o2(1:2^LL1);
            
            % Plotting data
            plot(app.UIAxes, kk, abs(o3));
            app.ReadyLabel.Text = [ app.file "Plotted!"];
            disp("Plot music : " + app.file + " at lp : " + app.ls + ", bp : " + app.bs + ", hp : " + app.hs);
            
        end

        % Button pushed function: ClearButton
        function Clearfunc(app, event)
            cla(app.UIAxes);
            disp("Clear Plot");
        end

        % Button pushed function: PlayButton
        function Playfunc(app, event)
            app.player = audioplayer(app.yr, app.fs);
            play(app.player);
            app.mflag = true;
            disp("Play music");
        end

        % Button pushed function: PauseResumeButton
        function Pausefunc(app, event)
            if app.mflag == true
                app.mflag = false;
                pause(app.player);
                disp("Pause music");
            else
                app.mflag = true;
                app.player = audioplayer(app.yr, app.fs);
                resume(app.player);
                disp("Resume music");
            end
        end

        % Button pushed function: StopButton
        function Stopfunc(app, event)
            stop(app.player);
            app.mflag = false;
            disp("Stop music");
        end

        % Value changed function: LowSlider
        function LowSliderchange(app, event)
            app.ls = app.LowSlider.Value;
            disp("Low Slider changed : " + app.ls);
        end

        % Value changed function: BandSlider
        function BandSliderchange(app, event)
            app.bs = app.BandSlider.Value;
            disp("Band Slider changed : " + app.bs);
        end

        % Value changed function: HighSlider
        function HighSliderchange(app, event)
            app.hs = app.HighSlider.Value;
            disp("High Slider changed : " + app.hs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {575, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FsradLabel_3 = uilabel(app.LeftPanel);
            app.FsradLabel_3.Text = '~ 1/16π Fs [rad]';
            app.LowSlider = uislider(app.LeftPanel);
            app.LowSlider.Limits = [0.5 1.5];
            app.LowSlider.MajorTicks = [0.5 1 1.5];
            app.LowSlider.MajorTickLabels = {'0.5', '1', '1.5'};
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @LowSliderchange, true);
            app.LowSlider.MinorTicks = [0.5 0.625 0.75 0.875 1 1.125 1.25 1.375 1.5];
            app.LowSlider.Value = 1;
            app.Fs18FsradLabel = uilabel(app.LeftPanel);
            app.Fs18FsradLabel.Text = '1/16π Fs ~ 1/8π Fs [rad]';
            app.BandSlider = uislider(app.LeftPanel);
            app.BandSlider.Limits = [0.5 1.5];
            app.BandSlider.MajorTicks = [0.5 1 1.5];
            app.BandSlider.MajorTickLabels = {'0.5', '1', '1.5'};
            app.BandSlider.ValueChangedFcn = createCallbackFcn(app, @BandSliderchange, true);
            app.BandSlider.MinorTicks = [0.5 0.625 0.75 0.875 1 1.125 1.25 1.375 1.5];
            app.BandSlider.Value = 1;
            app.FsradLabel_2 = uilabel(app.LeftPanel);
            app.FsradLabel_2.Text = '1/8π Fs [rad] ~ ';
            app.HighSlider = uislider(app.LeftPanel);
            app.HighSlider.Limits = [0.5 1.5];
            app.HighSlider.MajorTicks = [0.5 1 1.5];
            app.HighSlider.MajorTickLabels = {'0.5', '1', '1.5'};
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderchange, true);
            app.HighSlider.MinorTicks = [0.5 0.625 0.75 0.875 1 1.125 1.25 1.375 1.5];
            app.HighSlider.Value = 1;
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Frequency Domain Spectrum')
            xlabel(app.UIAxes, 'Frequency [rad]')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.5 3.5];
            app.UIAxes.XTick = [0 0.785398163397448 1.5707963267949 2.35619449019234 3.14159265358979];
            app.UIAxes.XTickLabel = {'0'; '\pi/4'; '\pi/2'; '3\pi/4'; '\pi'};
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadFileButton = uibutton(app.RightPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadfunc, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Plotfunc, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @Clearfunc, true);
            app.ClearButton.Text = 'Clear';
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Playfunc, true);
            app.PlayButton.Text = 'Play';
            app.ReadyLabel = uilabel(app.RightPanel);
            app.ReadyLabel.Text = 'Ready!';
            app.PauseResumeButton = uibutton(app.RightPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @Pausefunc, true);
            app.PauseResumeButton.Text = {'Pause &'; 'Resume'};
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stopfunc, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end